classdef TrasyR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TrasaPlot             matlab.ui.container.Panel
        PokaBtn               matlab.ui.control.Button
        dlTrasylbl            matlab.ui.control.Label
        H_max                 matlab.ui.control.Label
        H_min                 matlab.ui.control.Label
        CzasPrzejazdu         matlab.ui.control.Label
        TrasyroweroweLabel    matlab.ui.control.Label
        WagawkgLabel          matlab.ui.control.Label
        Waga                  matlab.ui.control.NumericEditField
        ChoosTrLbl            matlab.ui.control.Label
        ChooseTr              matlab.ui.control.ListBox
        PlecLbl               matlab.ui.control.Label
        Plec                  matlab.ui.control.DropDown
        NazwaLbl              matlab.ui.control.Label
        Opis                  matlab.ui.control.TextArea
        BackBtn               matlab.ui.control.Button
        Level                 matlab.ui.control.Label
        Wojew                 matlab.ui.control.Label
        Kraina                matlab.ui.control.Label
        RodzajMapy            matlab.ui.container.ButtonGroup
        StandardowyBtn        matlab.ui.control.RadioButton
        TopograficznyBtn      matlab.ui.control.RadioButton
        SatelitarnyBtn        matlab.ui.control.RadioButton
        DaneDoZapEnLbl        matlab.ui.control.Label
        ZapEnergLbl           matlab.ui.control.Label
        LogoGiK               matlab.ui.control.Image
        AutorzyprojektuLabel  matlab.ui.control.Label
        Autorzy               matlab.ui.control.TextArea
        podjazdy              matlab.ui.control.Label
        zjazdy                matlab.ui.control.Label
        przewWyk              matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global MainData POI
            
            MainData = readtable('MainData.xlsx');
            POI = readtable('POI.xlsx');
            
            app.ChooseTr.Items = MainData{:,2};
            app.ChooseTr.ItemsData = MainData{:,1};
            
        end

        % Button pushed function: PokaBtn
        function PokaBtnButtonPushed(app, event)
            global MainData POI
            format longG
            
            %Utworzenie panelu do wczytania mapy
            app.TrasaPlot = uipanel(app.UIFigure);
            app.TrasaPlot.AutoResizeChildren = 'on';
            app.TrasaPlot.Title = 'Przebieg trasy';
            app.TrasaPlot.Visible = 'off';
            app.TrasaPlot.HandleVisibility = 'off';
            app.TrasaPlot.FontWeight = 'bold';
            app.TrasaPlot.Position = [317 148 587 415];
            
            %zmiana widoczności
            %hide
            app.ChooseTr.Visible = 'Off';
            app.ChoosTrLbl.Visible ='Off';       
            app.Waga.Visible = 'Off';
            app.Plec.Visible = 'Off';
            app.PlecLbl.Visible = 'Off';
            app.WagawkgLabel.Visible = 'Off';    
            app.PokaBtn.Visible = 'Off';
            app.RodzajMapy.Visible = 'Off';
            app.DaneDoZapEnLbl.Visible = 'Off';
            app.LogoGiK.Visible = 'Off';
            app.Autorzy.Visible = 'Off';
            
            %wczytanie danych trasy
            Nr_trasy = app.ChooseTr.Value;
            plik = MainData{app.ChooseTr.Value,6};
            trasa = gpxread(string(plik));

            lat = trasa.Latitude;
            lon = trasa.Longitude;
            h = trasa.Elevation;
            s = deg2km(distance(lat(1:end-1), lon(1:end-1), lat(2:end), lon(2:end)),"earth");
            h(ismissing(s)) = [];
            s(ismissing(s)) = [];
            
            dh = h(2:end)-h(1:end-1);
            up = sum(dh(dh>=0));
            down = abs(sum(dh(dh<0)));
            odleglosc = sum(s);
            odcinki = cumsum(s);
            
            dl = s*1000;
            t = zeros(1, length(s));
            kcal = zeros(1, length(s));
            for i= 1:length(s)  
                [czas_odc lvl_odc] = F_czas(dl(i), dh(i));
                t(i) = czas_odc;
                kcal(i) = 7 * lvl_odc * czas_odc/60 * app.Waga.Value * app.Plec.Value;
            end

            energia = sum(kcal);
            czas = round(sum(t));
            [godz, minuty, sek] = F_s2hms(czas);
            minuty = roundn((minuty + sek/60),1);

            x =[0 odcinki];
            plot(app.przewWyk, x, h);
            app.przewWyk.XLim = [0, odleglosc];
            %rysowanie mapy
            if app.SatelitarnyBtn.Value == 1
                mapa = 'satellite';
            elseif app.StandardowyBtn.Value == 1
                mapa = 'streets-light';
            elseif app.TopograficznyBtn.Value == 1
                mapa = 'topographic';
            end
            POI2 = POI((POI{:, 1}) == Nr_trasy,:);
            M = POI2(string(POI2{:, 4}) == 'MOR', :);
            G = POI2(string(POI2{:, 4}) == 'Gastronomia', :);
            A = POI2(string(POI2{:, 4}) == 'Atrakcje', :);
            Noc = POI2(string(POI2{:, 4}) == 'Noclegi', :);
            
                       
            gx = geoaxes(app.TrasaPlot);
            geoplot(gx, lat, lon, "LineWidth", 1, "Color", [1 0 0]);
            
            hold (gx, 'on');          
            geoscatter(gx, M.Lat, M.Lon, 100, 'green', '^', ...
                'filled', 'MarkerEdgeColor', [0 0 0], 'MarkerFaceAlpha', .5);
            geoscatter(gx, G.Lat, G.Lon, 100, 'yellow', 'o', ...
                'filled', 'MarkerEdgeColor', [0 0 0], 'MarkerFaceAlpha', .5);
            geoscatter(gx, A.Lat, A.Lon, 100, 'cyan', 's', ...
                'filled', 'MarkerEdgeColor', [0 0 0], 'MarkerFaceAlpha', .5);
            geoscatter(gx, Noc.Lat, Noc.Lon, 100, 'magenta', 'd', ...
                'filled', 'MarkerEdgeColor', [0 0 0], 'MarkerFaceAlpha', .5);
            legend(gx, 'trasa', 'MOR', 'Gastronomia', 'Atrakcje', 'Noclegi', ...
                "Location", 'northeastoutside', 'FontSize', 9);
            legend(gx, "boxoff");
         
            gx.Basemap = mapa;
            gx.FontSize = 7;
            hold (gx, "off");
            
            %wyświetlenie informacji nt. trasy
            app.NazwaLbl.Text = MainData{app.ChooseTr.Value,2};
            app.Opis.Value = MainData{app.ChooseTr.Value,5};
            app.Level.Text = ('Poziom trud.: ' + string(MainData{app.ChooseTr.Value,7}));
            app.Kraina.Text = ('Królestwo: ' + string(MainData{app.ChooseTr.Value,4}));
            app.Wojew.Text = ('Województwo: ' + string(MainData{app.ChooseTr.Value,3}));
            app.dlTrasylbl.Text = sprintf('Długość trasy = %.3f km', sum(s));
            app.H_min.Text = sprintf('Wysokość minimalna = %.2f m', min(h));
            app.H_max.Text = sprintf('Wysokość maksymalna = %.2f m', max(h));
            app.CzasPrzejazdu.Text = sprintf('Czas przejazdu = %d:%d godz.', godz, minuty);
            app.ZapEnergLbl.Text = sprintf('Zużycie energii = %.1f kcal', energia); 
            app.zjazdy.Text = sprintf('Suma zjazdów = %.2f m', down); 
            app.podjazdy.Text = sprintf('Suma podjazdów = %.2f m', up); 
          
            %show               
            app.NazwaLbl.Visible ='On';
            app.Opis.Visible = 'On';
            app.dlTrasylbl.Visible ='On';
            app.H_min.Visible = 'On';
            app.H_max.Visible = 'On';
            app.CzasPrzejazdu.Visible = 'On';
            app.przewWyk.Visible = 'On';
            app.Level.Visible ='On';
            app.Kraina.Visible = 'On';
            app.Wojew.Visible = 'On';
            app.BackBtn.Visible = 'On';
            app.ZapEnergLbl.Visible = 'On';
            app.TrasaPlot.Visible = 'On';
            app.zjazdy.Visible = 'On';
            app.podjazdy.Visible = 'On';
        end

        % Button pushed function: BackBtn
        function BackBtnPushed(app, event)
            
            %Zmiana widoczności
            %% hide
            app.NazwaLbl.Visible ='Off';
            app.Opis.Visible = 'Off';
            app.dlTrasylbl.Visible ='Off';
            app.H_min.Visible = 'Off';
            app.H_max.Visible = 'Off';
            app.CzasPrzejazdu.Visible = 'Off';
            app.przewWyk.Visible = 'Off';
            app.TrasaPlot.Visible = 'Off';
            app.Level.Visible ='Off';
            app.Kraina.Visible = 'Off';
            app.Wojew.Visible = 'Off';
            app.BackBtn.Visible = 'Off';
            app.ZapEnergLbl.Visible = 'Off';
            app.zjazdy.Visible = 'Off';
            app.podjazdy.Visible = 'Off';
            
            %wyczyszczenie wykresu przewyższeń 
            plot(app.przewWyk, 0, 0);
            
            %usunięcie panelu z trasą 
            delete(app.TrasaPlot);
            
            %% show                     
            app.PokaBtn.Visible = 'On';
            app.ChooseTr.Visible = 'On';
            app.ChoosTrLbl.Visible ='On';                     
            app.Waga.Visible = 'On';            
            app.Plec.Visible = 'On';
            app.PlecLbl.Visible = 'On';
            app.WagawkgLabel.Visible = 'On';
            app.RodzajMapy.Visible = 'On';
            app.DaneDoZapEnLbl.Visible = 'On';
            app.LogoGiK.Visible = 'On';
            app.Autorzy.Visible = 'On';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrasaPlot = uipanel(app.UIFigure);
            app.TrasaPlot.Title = 'Przebieg trasy';
            app.TrasaPlot.Visible = 'off';
            app.PokaBtn = uibutton(app.UIFigure, 'push');
            app.PokaBtn.ButtonPushedFcn = createCallbackFcn(app, @PokaBtnButtonPushed, true);
            app.PokaBtn.Text = 'Pokaż trasę';
            app.dlTrasylbl = uilabel(app.UIFigure);
            app.dlTrasylbl.Visible = 'off';
            app.dlTrasylbl.Text = 'Długość trasy: ';
            app.H_max = uilabel(app.UIFigure);
            app.H_max.Visible = 'off';
            app.H_max.Text = 'Wysokość maksymalna: ';
            app.H_min = uilabel(app.UIFigure);
            app.H_min.Visible = 'off';
            app.H_min.Text = 'Wysokość minimalna: ';
            app.CzasPrzejazdu = uilabel(app.UIFigure);
            app.CzasPrzejazdu.Visible = 'off';
            app.CzasPrzejazdu.Text = 'Czas przejazdu: ';
            app.TrasyroweroweLabel = uilabel(app.UIFigure);
            app.TrasyroweroweLabel.Text = 'Trasy rowerowe';
            app.WagawkgLabel = uilabel(app.UIFigure);
            app.WagawkgLabel.Text = 'Waga w kg';
            app.Waga = uieditfield(app.UIFigure, 'numeric');
            app.Waga.Limits = [0 250];
            app.Waga.Value = 60;
            app.ChoosTrLbl = uilabel(app.UIFigure);
            app.ChoosTrLbl.Tag = 'ChooseLbl';
            app.ChoosTrLbl.Text = 'Wybierz trasę';
            app.ChooseTr = uilistbox(app.UIFigure);
            app.ChooseTr.Items = {'Trasy'};
            app.ChooseTr.Value = 'Trasy';
            app.PlecLbl = uilabel(app.UIFigure);
            app.PlecLbl.Text = 'Płeć';
            app.Plec = uidropdown(app.UIFigure);
            app.Plec.Items = {'Kobieta', 'Mężczyzna'};
            app.Plec.ItemsData = [0.0154 0.0175];
            app.Plec.Value = 0.0154;
            app.NazwaLbl = uilabel(app.UIFigure);
            app.NazwaLbl.Visible = 'off';
            app.NazwaLbl.Text = 'Nazwa trasy';
            app.Opis = uitextarea(app.UIFigure);
            app.Opis.Editable = 'off';
            app.Opis.Visible = 'off';
            app.BackBtn = uibutton(app.UIFigure, 'push');
            app.BackBtn.ButtonPushedFcn = createCallbackFcn(app, @BackBtnPushed, true);
            app.BackBtn.Visible = 'off';
            app.BackBtn.Text = {'Wróć '; 'do wyboru trasy'};
            app.Level = uilabel(app.UIFigure);
            app.Level.Visible = 'off';
            app.Level.Text = 'Długość trasy: ';
            app.Wojew = uilabel(app.UIFigure);
            app.Wojew.Visible = 'off';
            app.Wojew.Text = '';
            app.Kraina = uilabel(app.UIFigure);
            app.Kraina.Visible = 'off';
            app.Kraina.Text = '';
            app.RodzajMapy = uibuttongroup(app.UIFigure);
            app.RodzajMapy.Title = 'Wybierz podkład mapowy';
            app.StandardowyBtn = uiradiobutton(app.RodzajMapy);
            app.StandardowyBtn.Text = 'Standardowy';
            app.StandardowyBtn.Value = true;
            app.TopograficznyBtn = uiradiobutton(app.RodzajMapy);
            app.TopograficznyBtn.Text = 'Topograficzny';
            app.SatelitarnyBtn = uiradiobutton(app.RodzajMapy);
            app.SatelitarnyBtn.Text = 'Satelitarny';
            app.DaneDoZapEnLbl = uilabel(app.UIFigure);
            app.DaneDoZapEnLbl.Text = {'W celu obiczenia '; 'zapotrzebowania energetycznego'; 'podaj dane:'};
            app.ZapEnergLbl = uilabel(app.UIFigure);
            app.ZapEnergLbl.Visible = 'off';
            app.ZapEnergLbl.Text = 'Czas przejazdu: ';
            app.LogoGiK = uiimage(app.UIFigure);
            app.LogoGiK.ImageSource = 'WGiK-symbol.png';
            app.AutorzyprojektuLabel = uilabel(app.UIFigure);
            app.AutorzyprojektuLabel.Text = {'Autorzy'; 'projektu'};
            app.Autorzy = uitextarea(app.UIFigure);
            app.Autorzy.Value = {'Kinga LUBKIEWICZ'; 'Zofia MAGNUSZEWSKA'; 'Mikołaj NOWAK'; 'Bartosz RADZKI'; 'Kacper RAK'; 'Przemysław SOCIK'; 'Igor SOROCZYŃSKI'; 'Dominik WIŚNIEWSKI'};
            app.podjazdy = uilabel(app.UIFigure);
            app.podjazdy.Visible = 'off';
            app.podjazdy.Text = 'Czas przejazdu: ';
            app.zjazdy = uilabel(app.UIFigure);
            app.zjazdy.Visible = 'off';
            app.zjazdy.Text = 'Czas przejazdu: ';
            app.przewWyk = uiaxes(app.UIFigure);
            title(app.przewWyk, 'Przewyższenia')
            xlabel(app.przewWyk, 'Odległość [km]')
            ylabel(app.przewWyk, 'Wysokość [m]')
            app.przewWyk.XLim = [0 1];
            app.przewWyk.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrasyR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end